classdef ConstantCurrent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Title                      matlab.ui.control.Label
        ReturnButton               matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        SingleConductorTab         matlab.ui.container.Tab
        CurrentsSlider             matlab.ui.control.Slider
        Label_2                    matlab.ui.control.Label
        SMagneticFieldIntensityUI  matlab.ui.control.UIAxes
        MultipleConductorTab       matlab.ui.container.Tab
        Currents2Slider            matlab.ui.control.Slider
        y2EditField                matlab.ui.control.NumericEditField
        Currents3Slider            matlab.ui.control.Slider
        I3SliderLabel              matlab.ui.control.Label
        I2SliderLabel              matlab.ui.control.Label
        x2EditField                matlab.ui.control.NumericEditField
        Currents1Slider            matlab.ui.control.Slider
        x3EditField                matlab.ui.control.NumericEditField
        y1EditField                matlab.ui.control.NumericEditField
        x1EditField                matlab.ui.control.NumericEditField
        y3EditField                matlab.ui.control.NumericEditField
        y3200200EditFieldLabel     matlab.ui.control.Label
        x3200200EditFieldLabel     matlab.ui.control.Label
        y2200200EditFieldLabel     matlab.ui.control.Label
        x2200200EditFieldLabel     matlab.ui.control.Label
        I1SliderLabel              matlab.ui.control.Label
        y1200200EditFieldLabel     matlab.ui.control.Label
        x1200200EditFieldLabel     matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        MMagneticFieldIntensityUI  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CurrentsSlider
        function CurrentsSliderValueChanged(app, event)
            I = app.CurrentsSlider.Value;
            L=100;% 控制计算区域范围
            u0 = 4*pi*10^(-7);
            resol=50;%分辨率
            
            % 定义计算区域范围及分辨率
            x = linspace(-2*L,2*L,resol);
            y = linspace(-2*L,2*L,resol);
            [X,Y] = meshgrid(x,y);
            Z = zeros(size(X));
            
            % 计算磁场强度
            B = u0*I./(2*pi*sqrt(X.^2+Y.^2));
            
            % 将任意一点的磁场强度反向设置为垂直于Bz轴和这点与原点的矢径构成的平面方向
            Bz = zeros(size(X));
            Bx=-Y.*B*2; % 磁场方向x分量
            By=X.*B*2; % 磁场方向y分量
            
            % 绘制三维磁场图像
            surf(app.SMagneticFieldIntensityUI,X,Y,B,'FaceColor',[0.7,0.7,1]);
            hold(app.SMagneticFieldIntensityUI,'on');
            
            for add=1:resol
                [X2,Y2] = Random_inactivation(X,Y,0.995);% 随机失活
                Z=Z+I*8e-10;
                quiver3(app.SMagneticFieldIntensityUI,X2,Y2,Z,Bx,By,Bz,'color','black'); % 磁感应箭头图
                hold(app.SMagneticFieldIntensityUI,'on');
            end
            
            title(app.SMagneticFieldIntensityUI,'无限长直导线周围的磁场强度');
            xlabel(app.SMagneticFieldIntensityUI,'X轴');
            ylabel(app.SMagneticFieldIntensityUI,'Y轴');
            zlabel(app.SMagneticFieldIntensityUI,'B');

            function [u1,u2]=Random_inactivation(v,w,p) % v,w为同型矩阵，使v,w以p为概率随机失活
                % 生成一个与 A 大小相同的随机矩阵 B，并将其中小于 0.5 的元素设置为 0
                B = rand(size(v));
                v(B < p) = 0;
                w(B < p) = 0;
                u1=v;
                u2=w;
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x0=[app.x1EditField.Value,app.x2EditField.Value,app.x3EditField.Value];
            y0=[app.y1EditField.Value,app.y2EditField.Value,app.y3EditField.Value];
            
            n=length(x0); % 导线个数
            I=[app.Currents1Slider.Value,app.Currents2Slider.Value,app.Currents3Slider.Value]; % 各导线电流大小
            [~,~,X,Y]=Bx_By(I(1),x0(1),y0(1));% X,Y是计算域，即作图域
            Bx = zeros(size(X));
            By = zeros(size(Y));
            for i=1:n
                [Bx_temp,By_temp,~,~]=Bx_By(I(i),x0(i),y0(i));
                Bx=Bx+Bx_temp;
                By=By+By_temp;
            end
            B=sqrt(Bx.^2+By.^2);
            
            Bz = zeros(size(X));
            Z = zeros(size(X));
            incre=max(I)/10*8e-9; % 增量

            surf(app.MMagneticFieldIntensityUI,X,Y,B,'FaceColor',[0.7,0.7,1]);
            hold(app.MMagneticFieldIntensityUI,'on');

            for add=1:50
                [X2,Y2] = Random_inactivation(X,Y,0.8);% 随机失活
                Z=Z+incre;
                quiver3(app.MMagneticFieldIntensityUI,X2,Y2,Z,Bx*2e11,By*2e11,Bz,'color','black'); % 磁感应箭头图
                hold(app.MMagneticFieldIntensityUI,'on');
            end
            
            % 绘制三维磁场图像
            

            zlim(app.MMagneticFieldIntensityUI,[0 50*incre]);

            title(app.MMagneticFieldIntensityUI,'n条无限长直导线周围的磁场强度');
            xlabel(app.MMagneticFieldIntensityUI,'X轴');
            ylabel(app.MMagneticFieldIntensityUI,'Y轴');
            zlabel(app.MMagneticFieldIntensityUI,'B');


            % 计算磁场在x,y方向分量矩阵
            % 输入导线坐标[x0,y0],电流I
            % 输出磁场在x,y方向分量矩阵
            
            function [Bx,By,X0,Y0]=Bx_By(I,x0,y0)
                % 输入电流强度、导线坐标
                % x0,y0用于移动坐标系
            
                L=100;% 控制计算区域范围
                u0 = 4*pi*10^(-7);
                resol=50;%分辨率
                
                % 定义计算区域范围及分辨率
                x = linspace(-2*L,2*L,resol);
                y = linspace(-2*L,2*L,resol);
                [X0,Y0] = meshgrid(x,y);
                x=x-x0;
                y=y-y0;
                [X,Y] = meshgrid(x,y);
            
                % 计算磁场强度
                B = u0*I./(2*pi*sqrt(X.^2+Y.^2));
                
                % 首先计算出矢量的方位角度theta
                theta = atan2(Y,X); % 矢径与X轴的夹角
                Bx=B.*sin(theta); % 磁场方向x分量
                By=B.*cos(theta); % 磁场方向y分量
            end


            function [u1,u2]=Random_inactivation(v,w,p) % v,w为同型矩阵，使v,w以p为概率随机失活
                % 生成一个与 A 大小相同的随机矩阵 B，并将其中小于 0.5 的元素设置为 0
                B = rand(size(v));
                v(B < p) = 0;
                w(B < p) = 0;
                u1=v;
                u2=w;
            end

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            main();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleConductorTab = uitab(app.TabGroup);
            app.SingleConductorTab.Title = '单导线';
            app.SMagneticFieldIntensityUI = uiaxes(app.SingleConductorTab);
            title(app.SMagneticFieldIntensityUI, '单导线')
            xlabel(app.SMagneticFieldIntensityUI, 'X')
            ylabel(app.SMagneticFieldIntensityUI, 'Y')
            zlabel(app.SMagneticFieldIntensityUI, 'B')
            app.Label_2 = uilabel(app.SingleConductorTab);
            app.Label_2.Text = '电流';
            app.CurrentsSlider = uislider(app.SingleConductorTab);
            app.CurrentsSlider.ValueChangedFcn = createCallbackFcn(app, @CurrentsSliderValueChanged, true);
            app.MultipleConductorTab = uitab(app.TabGroup);
            app.MultipleConductorTab.Title = '多导线';
            app.MMagneticFieldIntensityUI = uiaxes(app.MultipleConductorTab);
            title(app.MMagneticFieldIntensityUI, '多导线')
            xlabel(app.MMagneticFieldIntensityUI, 'X')
            ylabel(app.MMagneticFieldIntensityUI, 'Y')
            zlabel(app.MMagneticFieldIntensityUI, 'Z')
            app.PlotButton = uibutton(app.MultipleConductorTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = '绘图';
            app.x1200200EditFieldLabel = uilabel(app.MultipleConductorTab);
            app.x1200200EditFieldLabel.Text = 'x1(-200,200)';
            app.y1200200EditFieldLabel = uilabel(app.MultipleConductorTab);
            app.y1200200EditFieldLabel.Text = 'y1(-200,200)';
            app.I1SliderLabel = uilabel(app.MultipleConductorTab);
            app.I1SliderLabel.Text = '电流I1';
            app.x2200200EditFieldLabel = uilabel(app.MultipleConductorTab);
            app.x2200200EditFieldLabel.Text = 'x2(-200,200)';
            app.y2200200EditFieldLabel = uilabel(app.MultipleConductorTab);
            app.y2200200EditFieldLabel.Text = 'y2(-200,200)';
            app.x3200200EditFieldLabel = uilabel(app.MultipleConductorTab);
            app.x3200200EditFieldLabel.Text = 'x3(-200,200)';
            app.y3200200EditFieldLabel = uilabel(app.MultipleConductorTab);
            app.y3200200EditFieldLabel.Text = 'y3(-200,200)';
            app.y3EditField = uieditfield(app.MultipleConductorTab, 'numeric');
            app.y3EditField.Limits = [-200 200];
            app.x1EditField = uieditfield(app.MultipleConductorTab, 'numeric');
            app.x1EditField.Limits = [-200 200];
            app.y1EditField = uieditfield(app.MultipleConductorTab, 'numeric');
            app.y1EditField.Limits = [-200 200];
            app.x3EditField = uieditfield(app.MultipleConductorTab, 'numeric');
            app.x3EditField.Limits = [-200 200];
            app.Currents1Slider = uislider(app.MultipleConductorTab);
            app.Currents1Slider.Limits = [0 30];
            app.x2EditField = uieditfield(app.MultipleConductorTab, 'numeric');
            app.x2EditField.Limits = [-200 200];
            app.I2SliderLabel = uilabel(app.MultipleConductorTab);
            app.I2SliderLabel.Text = '电流I2';
            app.I3SliderLabel = uilabel(app.MultipleConductorTab);
            app.I3SliderLabel.Text = '电流I3';
            app.Currents3Slider = uislider(app.MultipleConductorTab);
            app.Currents3Slider.Limits = [0 30];
            app.y2EditField = uieditfield(app.MultipleConductorTab, 'numeric');
            app.y2EditField.Limits = [-200 200];
            app.Currents2Slider = uislider(app.MultipleConductorTab);
            app.Currents2Slider.Limits = [0 30];
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = '返回';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '无限长直导线周围的磁场强度';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConstantCurrent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end