classdef PointCharges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ReturnButton             matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        SingleChargeTab          matlab.ui.container.Tab
        Instruction              matlab.ui.control.Label
        QSlider                  matlab.ui.control.Slider
        QSliderLabel             matlab.ui.control.Label
        TextArea_2Label          matlab.ui.control.Label
        Conclusion               matlab.ui.control.TextArea
        Explanation              matlab.ui.control.TextArea
        TextAreaLabel            matlab.ui.control.Label
        ElectricFiledDisUI       matlab.ui.control.UIAxes
        EquipotentialLinesDisUI  matlab.ui.control.UIAxes
        ThreeChargesTab          matlab.ui.container.Tab
        Q1Slider                 matlab.ui.control.Slider
        Q2Slider                 matlab.ui.control.Slider
        Q3Slider                 matlab.ui.control.Slider
        Q3SliderLabel            matlab.ui.control.Label
        Q2SliderLabel            matlab.ui.control.Label
        Q1SliderLabel            matlab.ui.control.Label
        ElecFieldsAndEquipSurUI  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function Draw(app)

            q1 = app.Q1Slider.Value;
            q2 = app.Q2Slider.Value;
            q3 = app.Q3Slider.Value;
            
            %电量比 忽略4pi*e的常数
            xm=4.5;
            ym=4.5;
            x=linspace(-xm,xm);
            y=linspace(-ym,ym);
            [X,Y]=meshgrid(x,y);%设置坐标网点
            R1=sqrt(X.^2+Y.^2);
            R2=sqrt((X-1).^2+(Y+1).^2);
            R3=sqrt((X+1).^2+(Y+1).^2);
            
            U=q1./R1+q2./R2+q3./R3;
            u=-40:0.3:40;
            
            contour(app.ElecFieldsAndEquipSurUI,X,Y,U,u,'--')%画等势线
            
            [Ex,Ey]=gradient(-U,x(2)-x(1),y(2)-y(1));
            r0=0.1;%电场线起点半径
            a=20;%设置电场线角度间隔
            b=(a:a:360-a)*pi/180;%电荷电场线的始末角度与步长
            x1=r0*cos(b);
            y1=r0*sin(b);
            streamline(app.ElecFieldsAndEquipSurUI,X,Y,Ex,Ey,x1,y1)%画电场线
            
            c=(-180-a:a:180-a)*pi/180;
            x2=r0*cos(c)+1;
            y2=r0*sin(c)-1;
            streamline(app.ElecFieldsAndEquipSurUI,X,Y,Ex,Ey,x2,y2)%画电场线
            
            d=(-180-a:a:180-a)*pi/180;
            x3=r0*cos(d)-1;
            y3=r0*sin(d)-1;
            streamline(app.ElecFieldsAndEquipSurUI,X,Y,Ex,Ey,x3,y3)%画电场线
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: QSlider
        function QSliderValueChanged(app, event)
            q = app.QSlider.Value;
            x=-4.5:0.08:4.5;
            [x,y]=meshgrid(x);
            plot(app.ElectricFiledDisUI,0,0,'o','MarkerSize',12);
            
            z=q./(x.^2+y.^2);
            meshc(app.ElectricFiledDisUI,x,y,z)
            
            %shading interp
            %colormap jet
                
            
            %电量比 忽略4pi*e的常数
            xm=4.5;
            ym=4.5;
            x1=linspace(-xm,xm);
            y1=linspace(-ym,ym);
            [X,Y]=meshgrid(x1,y1);%设置坐标网点
            R=sqrt(X.^2+Y.^2);
            U=q./R;
            u=-40:0.3:40;
            
            contour(app.EquipotentialLinesDisUI,X,Y,U,u,'--')%画等势线
            
            [Ex,Ey]=gradient(-U,x1(2)-x1(1),y1(2)-y1(1));
            r0=0.1;%电场线起点半径
            a=20;%设置电场线角度间隔
            b=(a:a:360-a)*pi/180;%电荷电场线的始末角度与步长
            x1=r0*cos(b);
            y1=r0*sin(b);
            streamline(app.EquipotentialLinesDisUI,X,Y,Ex,Ey,x1,y1)%画电场线
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            app.Draw();
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            app.Draw();
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
            app.Draw();            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            main();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleChargeTab = uitab(app.TabGroup);
            app.SingleChargeTab.Title = '单电荷';
            app.EquipotentialLinesDisUI = uiaxes(app.SingleChargeTab);
            title(app.EquipotentialLinesDisUI, '等电势线分布')
            xlabel(app.EquipotentialLinesDisUI, 'X')
            ylabel(app.EquipotentialLinesDisUI, 'Y')
            zlabel(app.EquipotentialLinesDisUI, 'Z')
            app.EquipotentialLinesDisUI.XLim = [-4.5 4.5];
            app.EquipotentialLinesDisUI.YLim = [-4.5 4.5];
            colormap(app.EquipotentialLinesDisUI, 'turbo')
            app.ElectricFiledDisUI = uiaxes(app.SingleChargeTab);
            title(app.ElectricFiledDisUI, '电场分布')
            xlabel(app.ElectricFiledDisUI, 'X')
            ylabel(app.ElectricFiledDisUI, 'Y')
            zlabel(app.ElectricFiledDisUI, 'Z')
            app.ElectricFiledDisUI.XLim = [-4.5 4.5];
            app.ElectricFiledDisUI.YLim = [-4 4];
            app.ElectricFiledDisUI.ZLim = [0 60];
            app.ElectricFiledDisUI.CLim = [0 30];
            colormap(app.ElectricFiledDisUI, 'jet')
            app.TextAreaLabel = uilabel(app.SingleChargeTab);
            app.TextAreaLabel.Text = '说明';
            app.Explanation = uitextarea(app.SingleChargeTab);
            app.Explanation.Value = {'电场的梯度如左图的直线'};
            app.Conclusion = uitextarea(app.SingleChargeTab);
            app.Conclusion.Value = {'当空间点电荷电量增加时，空间固定点电场变大，电势也变大'};
            app.TextArea_2Label = uilabel(app.SingleChargeTab);
            app.TextArea_2Label.Text = '结论';
            app.QSliderLabel = uilabel(app.SingleChargeTab);
            app.QSliderLabel.Text = 'Q';
            app.QSlider = uislider(app.SingleChargeTab);
            app.QSlider.ValueChangedFcn = createCallbackFcn(app, @QSliderValueChanged, true);
            app.Instruction = uilabel(app.SingleChargeTab);
            app.Instruction.Text = '设置电荷量';
            app.ThreeChargesTab = uitab(app.TabGroup);
            app.ThreeChargesTab.Title = '三电荷';
            app.ElecFieldsAndEquipSurUI = uiaxes(app.ThreeChargesTab);
            title(app.ElecFieldsAndEquipSurUI, '电场与等势面')
            xlabel(app.ElecFieldsAndEquipSurUI, 'X')
            ylabel(app.ElecFieldsAndEquipSurUI, 'Y')
            zlabel(app.ElecFieldsAndEquipSurUI, 'Z')
            app.Q1SliderLabel = uilabel(app.ThreeChargesTab);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q2SliderLabel = uilabel(app.ThreeChargesTab);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q3SliderLabel = uilabel(app.ThreeChargesTab);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.ThreeChargesTab);
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q2Slider = uislider(app.ThreeChargesTab);
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q1Slider = uislider(app.ThreeChargesTab);
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = '返回';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PointCharges

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end